classdef MotorControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TextArea      matlab.ui.control.TextArea
        Label_2       matlab.ui.control.Label
        ButtonPlan    matlab.ui.control.Button
        ButtonOpen    matlab.ui.control.Button
        ButtonDetect  matlab.ui.control.Button
        ButtonGra     matlab.ui.control.Button
        ButtonBegin   matlab.ui.control.Button
        LabelNo6      matlab.ui.control.Label
        LabelNo5      matlab.ui.control.Label
        LabelNo4      matlab.ui.control.Label
        LabelNo3      matlab.ui.control.Label
        LabelNo2      matlab.ui.control.Label
        LabelNo1      matlab.ui.control.Label
        LabelYes6     matlab.ui.control.Label
        LabelYes5     matlab.ui.control.Label
        LabelYes4     matlab.ui.control.Label
        LabelYes3     matlab.ui.control.Label
        LabelYes2     matlab.ui.control.Label
        LabelYes1     matlab.ui.control.Label
        ButtonMotor6  matlab.ui.control.Button
        ButtonMotor5  matlab.ui.control.Button
        ButtonMotor4  matlab.ui.control.Button
        ButtonMotor3  matlab.ui.control.Button
        ButtonMotor2  matlab.ui.control.Button
        ButtonMotor1  matlab.ui.control.Button
        Label         matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        JointLimits = [ -150  170
                 -80  110
                 -60  90
                 -170 140
                 -150 150
                 -180 180
                ];
        tSet
        qSet
        motorChecked
    end
    
    properties (Access = private)
        obj_pos = []; %物体的坐标位置
    end
    
    methods (Access = private)
        
        function clear_uiaxes(app)
            app.tSet = [];
            app.qSet = [];
            
            app.LabelYes1.Visible = false;   app.LabelNo1.Visible = false;
            app.LabelYes2.Visible = false;   app.LabelNo2.Visible = false;
            app.LabelYes3.Visible = false;   app.LabelNo3.Visible = false;
            app.LabelYes4.Visible = false;   app.LabelNo4.Visible = false;
            app.LabelYes5.Visible = false;   app.LabelNo5.Visible = false;
            app.LabelYes6.Visible = false;   app.LabelNo6.Visible = false;
            app.motorChecked = [];
            app.ButtonMotor1.Enable = false;
            app.ButtonMotor2.Enable = false;
            app.ButtonMotor3.Enable = false;
            app.ButtonMotor4.Enable = false;
            app.ButtonMotor5.Enable = false;
            app.ButtonMotor6.Enable = false;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonMotor1
        function ButtonMotor1Pushed(app, event)
            qq = app.qSet( :, 1 );              
            bOK1 = qq >= app.JointLimits( 1, 1);
            bOK2 = qq <= app.JointLimits( 1, 2);
            if all( bOK1 ==1 ) && all( bOK2 == 1 )
                app.LabelYes1.Visible = true;
                app.motorChecked(1) = true;
            else
                app.LabelNo1.Visible = true;
            end
            app.UIAxes.NextPlot = 'replace';
            plot( app.UIAxes, app.tSet, app.qSet( :, 1) );
            app.UIAxes.NextPlot = 'add';
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 1, 1) app.JointLimits( 1, 1)], 'r-' );
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 1, 2) app.JointLimits( 1, 2)], 'b-' );

            if ( length( app.motorChecked ) == 6 ) && all( app.motorChecked == true )
                app.ButtonBegin.Enable = true;
            end

        end

        % Button pushed function: ButtonOpen
        function ButtonOpenPushed(app, event)
            data = load('data\JointMoveDeg.txt');
            app.Label.Visible = "on";
            app.tSet = [];
            app.qSet = [];
            
            app.LabelYes1.Visible = false;   app.LabelNo1.Visible = false;
            app.LabelYes2.Visible = false;   app.LabelNo2.Visible = false;
            app.LabelYes3.Visible = false;   app.LabelNo3.Visible = false;
            app.LabelYes4.Visible = false;   app.LabelNo4.Visible = false;
            app.LabelYes5.Visible = false;   app.LabelNo5.Visible = false;
            app.LabelYes6.Visible = false;   app.LabelNo6.Visible = false;
            app.motorChecked = [];
            cla(app.UIAxes);
            if length(data) < 1
                msgbox( '数据文件或索引文件不能正确打开!' )
                return;
            else
                app.tSet = data( :, 1);
                app.qSet = data( :, 2 : 7 );
%                 app.ButtonPlan.Enable = true;               
                app.ButtonMotor1.Enable = true;
                app.ButtonMotor2.Enable = true;
                app.ButtonMotor3.Enable = true;
                app.ButtonMotor4.Enable = true;
                app.ButtonMotor5.Enable = true;
                app.ButtonMotor6.Enable = true;
            end
            
        end

        % Button pushed function: ButtonMotor2
        function ButtonMotor2Pushed(app, event)
            qq = app.qSet( :, 2 );              
            bOK1 = qq >= app.JointLimits( 2, 1);
            bOK2 = qq <= app.JointLimits( 2, 2);
            if all( bOK1 ==1 ) && all( bOK2 == 1 )
                app.LabelYes2.Visible = true;
                app.motorChecked(2) = true;
            else
                app.LabelNo2.Visible = true;
            end
            app.UIAxes.NextPlot = 'replace';
            plot( app.UIAxes, app.tSet, app.qSet( :, 2) );
            app.UIAxes.NextPlot = 'add';
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 2, 1) app.JointLimits( 2, 1)], 'r-' );
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 2, 2) app.JointLimits( 2, 2)], 'b-' );

            if ( length( app.motorChecked ) == 6 ) && all( app.motorChecked == true )
                app.ButtonBegin.Enable = true;
            end

        end

        % Button pushed function: ButtonMotor3
        function ButtonMotor3Pushed(app, event)
            qq = app.qSet( :, 1 );              
            bOK1 = qq >= app.JointLimits( 3, 1);
            bOK2 = qq <= app.JointLimits( 3, 2);
            if all( bOK1 ==1 ) && all( bOK2 == 1 )
                app.LabelYes3.Visible = true;
                app.motorChecked(3) = true;
            else
                app.LabelNo3.Visible = true;
            end
            app.UIAxes.NextPlot = 'replace';
            plot( app.UIAxes, app.tSet, app.qSet( :, 3) );
            app.UIAxes.NextPlot = 'add';
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 3, 1) app.JointLimits( 3, 1)], 'r-' );
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 3, 2) app.JointLimits( 3, 2)], 'b-' );

            if ( length( app.motorChecked ) == 6 ) && all( app.motorChecked == true )
                app.ButtonBegin.Enable = true;
            end
            
        end

        % Button pushed function: ButtonMotor4
        function ButtonMotor4Pushed(app, event)
            qq = app.qSet( :, 4 );              
            bOK1 = qq >= app.JointLimits( 4, 1);
            bOK2 = qq <= app.JointLimits( 4, 2);
            if all( bOK1 ==1 ) && all( bOK2 == 1 )
                app.LabelYes4.Visible = true;
                app.motorChecked(4) = true;
            else
                app.LabelNo4.Visible = true;
            end
            app.UIAxes.NextPlot = 'replace';
            plot( app.UIAxes, app.tSet, app.qSet( :, 4) );
            app.UIAxes.NextPlot = 'add';
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 4, 1) app.JointLimits( 4, 1)], 'r-' );
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 4, 2) app.JointLimits( 4, 2)], 'b-' );
  
            if ( length( app.motorChecked ) == 6 ) && all( app.motorChecked == true )
                app.ButtonBegin.Enable = true;
            end
            
        end

        % Button pushed function: ButtonMotor5
        function ButtonMotor5Pushed(app, event)
            qq = app.qSet( :, 5 );              
            bOK1 = qq >= app.JointLimits( 5, 1);
            bOK2 = qq <= app.JointLimits( 5, 2);
            if all( bOK1 ==1 ) && all( bOK2 == 1 )
                app.LabelYes5.Visible = true;
                app.motorChecked(5) = true;
            else
                app.LabelNo5.Visible = true;
            end
            app.UIAxes.NextPlot = 'replace';
            plot( app.UIAxes, app.tSet, app.qSet( :, 5) );
            app.UIAxes.NextPlot = 'add';
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 5, 1) app.JointLimits( 5, 1)], 'r-' );
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 5, 2) app.JointLimits( 5, 2)], 'b-' );

            if ( length( app.motorChecked ) == 6 ) && all( app.motorChecked == true )
                app.ButtonBegin.Enable = true;
            end

        end

        % Button pushed function: ButtonMotor6
        function ButtonMotor6Pushed(app, event)
            qq = app.qSet( :, 6 );              
            bOK1 = qq >= app.JointLimits( 6, 1);
            bOK2 = qq <= app.JointLimits( 6, 2);
            if all( bOK1 ==1 ) && all( bOK2 == 1 )
                app.LabelYes6.Visible = true;
                app.motorChecked(6) = true;
            else
                app.LabelNo6.Visible = true;
            end
            app.UIAxes.NextPlot = 'replace';
            plot( app.UIAxes, app.tSet, app.qSet( :, 6) );
            app.UIAxes.NextPlot = 'add';
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 6, 1) app.JointLimits( 6, 1)], 'r-' );
            plot( app.UIAxes, [ app.tSet(1) app.tSet(end)], [ app.JointLimits( 6, 2) app.JointLimits( 6, 2)], 'b-' );
  
            if ( length( app.motorChecked ) == 6 ) && all( app.motorChecked == true )
                app.ButtonBegin.Enable = true;
            end
        end

        % Button pushed function: ButtonPlan
        function ButtonPlanPushed(app, event)
            app.ButtonBegin.Enable = "off";
            app.ButtonPlan.Enable = "off";
            app.TextArea.Value = '正在运动规划';
            clear_uiaxes(app);
            drawnow;
            if height(app.obj_pos) == 0
                app.TextArea.Value = "没有检测到物体位置";
                 app.ButtonPlan.Enable = "on";
                drawnow;
                return;
            end
            
            bool = Plan(app.obj_pos);
            if bool == false
                app.TextArea.Value = '超出运动范围';
                 app.ButtonPlan.Enable = "on";
                drawnow;
                return;
            end
            app.ButtonOpen.Enable = "on";
             app.ButtonPlan.Enable = "on";
            app.TextArea.Value = '动规划完成';
        end

        % Button pushed function: ButtonBegin
        function ButtonBeginPushed(app, event)
            app.TextArea.Value = '机械臂正在运动';
            app.ButtonBegin.Enable = 'off';
            clear_uiaxes(app);
            drawnow;
            exefile='p2p.exe';
            exepath=fullfile('.\',exefile);
            para=[' ','"data/JointMoveDeg.txt"'];
            cmd=[exepath,para];
            status = system(cmd);
            if status ~= 0
                app.TextArea.Value = '运动失败';
                drawnow;
                return;
            end
            app.TextArea.Value = '运动完成';
        end

        % Button pushed function: ButtonGra
        function ButtonGraPushed(app, event)
            app.LabelYes1.Visible = false;
            app.ButtonGra.Enable = "off";
            app.TextArea.Value = '正在运行相机';
            clear_uiaxes(app);
            drawnow;
            %调用相机可执行程序
            exefile='test.exe';
            exepath=fullfile('.\run/',exefile);
            status  = system(exepath); 
           app.UIAxes.Visible = 'on';
           app.UIAxes.NextPlot = 'replace';
           %显示图片
           im = imread('data/data.jpg');
           imshow(im, 'Parent',app.UIAxes);
            
            app.ButtonDetect.Enable = true;
            app.ButtonGra.Enable = 'on';
            app.TextArea.Value = '拍摄完成';
        end

        % Button pushed function: ButtonDetect
        function ButtonDetectPushed(app, event)
            app.LabelYes1.Visible = false;
            app.TextArea.Value = '正在运行标志点检测';
            clear_uiaxes(app);
            drawnow;
            %标志点识别
            [rgb, p_pos] = detect_point();
            app.UIAxes.NextPlot = 'replace';
            imshow(rgb, 'Parent',app.UIAxes);
            app.UIAxes.NextPlot = 'add';
            %判断是否识别到两个标志点
            if height(p_pos) == 2
                plot( app.UIAxes, p_pos(1,1),p_pos(1,2), 'g*');
                plot( app.UIAxes, p_pos(2,1),p_pos(2,2), 'g*');
            else
                app.TextArea.Value = '未检测到标志点';
                drawnow;
                return
            end
            app.TextArea.Value = '正在运行物体检测';
            drawnow;
            %物体识别
            [app.obj_pos, Jps] = obj_dect(rgb);
            if height(app.obj_pos) == 0
                app.TextArea.Value = '未检测到物体';
                drawnow;
                return;
            end
            for i = 1 : height(app.obj_pos)
                plot( app.UIAxes, app.obj_pos(i,1),app.obj_pos(i,2), 'r*');
            end
            for i = 1 : height(Jps)
                plot( app.UIAxes, Jps(i,1),Jps(i,2), 'b*');
            end
            app.obj_pos = Calculate_coordinates(app.obj_pos, p_pos);
            app.TextArea.Value = '运行完成';
            app.ButtonPlan.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '电机角度变化')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '角度')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '机器人运动仿真软件';
            app.ButtonMotor1 = uibutton(app.UIFigure, 'push');
            app.ButtonMotor1.ButtonPushedFcn = createCallbackFcn(app, @ButtonMotor1Pushed, true);
            app.ButtonMotor1.Enable = 'off';
            app.ButtonMotor1.Text = '电机一角度变化';
            app.ButtonMotor2 = uibutton(app.UIFigure, 'push');
            app.ButtonMotor2.ButtonPushedFcn = createCallbackFcn(app, @ButtonMotor2Pushed, true);
            app.ButtonMotor2.Enable = 'off';
            app.ButtonMotor2.Text = '电机二角度变化';
            app.ButtonMotor3 = uibutton(app.UIFigure, 'push');
            app.ButtonMotor3.ButtonPushedFcn = createCallbackFcn(app, @ButtonMotor3Pushed, true);
            app.ButtonMotor3.Enable = 'off';
            app.ButtonMotor3.Text = '电机三角度变化';
            app.ButtonMotor4 = uibutton(app.UIFigure, 'push');
            app.ButtonMotor4.ButtonPushedFcn = createCallbackFcn(app, @ButtonMotor4Pushed, true);
            app.ButtonMotor4.Enable = 'off';
            app.ButtonMotor4.Text = '电机四角度变化';
            app.ButtonMotor5 = uibutton(app.UIFigure, 'push');
            app.ButtonMotor5.ButtonPushedFcn = createCallbackFcn(app, @ButtonMotor5Pushed, true);
            app.ButtonMotor5.Enable = 'off';
            app.ButtonMotor5.Text = '电机五角度变化';
            app.ButtonMotor6 = uibutton(app.UIFigure, 'push');
            app.ButtonMotor6.ButtonPushedFcn = createCallbackFcn(app, @ButtonMotor6Pushed, true);
            app.ButtonMotor6.Enable = 'off';
            app.ButtonMotor6.Text = '电机六角度变化';
            app.LabelYes1 = uilabel(app.UIFigure);
            app.LabelYes1.Visible = 'off';
            app.LabelYes1.Text = '验证成功';
            app.LabelYes2 = uilabel(app.UIFigure);
            app.LabelYes2.Visible = 'off';
            app.LabelYes2.Text = '验证成功';
            app.LabelYes3 = uilabel(app.UIFigure);
            app.LabelYes3.Visible = 'off';
            app.LabelYes3.Text = '验证成功';
            app.LabelYes4 = uilabel(app.UIFigure);
            app.LabelYes4.Visible = 'off';
            app.LabelYes4.Text = '验证成功';
            app.LabelYes5 = uilabel(app.UIFigure);
            app.LabelYes5.Visible = 'off';
            app.LabelYes5.Text = '验证成功';
            app.LabelYes6 = uilabel(app.UIFigure);
            app.LabelYes6.Visible = 'off';
            app.LabelYes6.Text = '验证成功';
            app.LabelNo1 = uilabel(app.UIFigure);
            app.LabelNo1.Visible = 'off';
            app.LabelNo1.Text = '超出范围';
            app.LabelNo2 = uilabel(app.UIFigure);
            app.LabelNo2.Visible = 'off';
            app.LabelNo2.Text = '超出范围';
            app.LabelNo3 = uilabel(app.UIFigure);
            app.LabelNo3.Visible = 'off';
            app.LabelNo3.Text = '超出范围';
            app.LabelNo4 = uilabel(app.UIFigure);
            app.LabelNo4.Visible = 'off';
            app.LabelNo4.Text = '超出范围';
            app.LabelNo5 = uilabel(app.UIFigure);
            app.LabelNo5.Visible = 'off';
            app.LabelNo5.Text = '超出范围';
            app.LabelNo6 = uilabel(app.UIFigure);
            app.LabelNo6.Visible = 'off';
            app.LabelNo6.Text = '超出范围';
            app.ButtonBegin = uibutton(app.UIFigure, 'push');
            app.ButtonBegin.ButtonPushedFcn = createCallbackFcn(app, @ButtonBeginPushed, true);
            app.ButtonBegin.Enable = 'off';
            app.ButtonBegin.Text = '运动';
            app.ButtonGra = uibutton(app.UIFigure, 'push');
            app.ButtonGra.ButtonPushedFcn = createCallbackFcn(app, @ButtonGraPushed, true);
            app.ButtonGra.Text = '拍摄图片';
            app.ButtonDetect = uibutton(app.UIFigure, 'push');
            app.ButtonDetect.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetectPushed, true);
            app.ButtonDetect.Enable = 'off';
            app.ButtonDetect.Text = '图像识别';
            app.ButtonOpen = uibutton(app.UIFigure, 'push');
            app.ButtonOpen.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenPushed, true);
            app.ButtonOpen.Enable = 'off';
            app.ButtonOpen.Text = '关节角度校验';
            app.ButtonPlan = uibutton(app.UIFigure, 'push');
            app.ButtonPlan.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlanPushed, true);
            app.ButtonPlan.Enable = 'off';
            app.ButtonPlan.Text = '轨迹规划';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '程序运行检测';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotorControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end